classdef LPFSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ButtonOK               matlab.ui.control.Button
        ButtonCancel           matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        FpassEditFieldLabel    matlab.ui.control.Label
        FpassEditField         matlab.ui.control.NumericEditField
        FstopEditFieldLabel    matlab.ui.control.Label
        FstopEditField         matlab.ui.control.NumericEditField
        FsEditFieldLabel       matlab.ui.control.Label
        FsEditField            matlab.ui.control.NumericEditField
        rpEditFieldLabel       matlab.ui.control.Label
        rpEditField            matlab.ui.control.NumericEditField
        rsEditFieldLabel       matlab.ui.control.Label
        rsEditField            matlab.ui.control.NumericEditField
        FpassEditFieldLabel_2  matlab.ui.control.Label
        FpassEditFieldLabel_3  matlab.ui.control.Label
        FpassEditFieldLabel_4  matlab.ui.control.Label
        FpassEditFieldLabel_5  matlab.ui.control.Label
        FpassEditFieldLabel_6  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure,'center');
            filename = '低通.png';
            filepath = '.\Settings\';
            path=[filepath filename];
%             [img,~]=imread(path);
%             axes('Parent',app.UIAxes);
            imshow(path,'Parent',app.UIAxes);

        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            cla(app.UIAxes,'reset');  %  cla(app.UIAxes);
            Fpass = app.FpassEditField.Value;   
            Fstop = app.FstopEditField.Value;  
            fs = app.FsEditField.Value; 
            rpL = app.rpEditField.Value; 
            rsL = app.rsEditField.Value;
            [bL,~] = LPFDesign(Fpass,Fstop,fs,rpL,rsL);
            N = 2^12;
            FiltMag = abs(fft(bL,N));                                   % bL 是滤波器系数，FFT变成幅频特性。
            FiltMag = 20*log10(FiltMag(1:N/2));                         % 带通滤波器在中频上。 
            x_filtmag = linspace(0,fs/2,N/2); 
            plot(app.UIAxes,x_filtmag,FiltMag);
            xlabel(app.UIAxes,'频率范围(单位：Hz)','Color',[0 0 1]);
            ylabel(app.UIAxes,'(单位：dB)','Color',[0 0 1]);
            title(app.UIAxes,'低通滤波器幅频特性','Color',[0 0 1]);
            save .\Settings\LPFParameter.mat bL x_filtmag FiltMag % -append     % 2019年6月4日修改

        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = '滤波器设置';
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = '设置滤波器';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Text = '关闭';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.FpassEditFieldLabel = uilabel(app.Panel);
            app.FpassEditFieldLabel.Text = 'Fpass';
            app.FpassEditField = uieditfield(app.Panel, 'numeric');
            app.FpassEditField.Limits = [0 Inf];
            app.FpassEditField.Value = 1;
            app.FstopEditFieldLabel = uilabel(app.Panel);
            app.FstopEditFieldLabel.Text = 'Fstop';
            app.FstopEditField = uieditfield(app.Panel, 'numeric');
            app.FstopEditField.Limits = [0 Inf];
            app.FstopEditField.Value = 5;
            app.FsEditFieldLabel = uilabel(app.Panel);
            app.FsEditFieldLabel.Text = 'Fs';
            app.FsEditField = uieditfield(app.Panel, 'numeric');
            app.FsEditField.Limits = [0 Inf];
            app.FsEditField.Editable = 'off';
            app.FsEditField.Value = 100;
            app.rpEditFieldLabel = uilabel(app.Panel);
            app.rpEditFieldLabel.Text = 'rp';
            app.rpEditField = uieditfield(app.Panel, 'numeric');
            app.rpEditField.Limits = [0 Inf];
            app.rpEditField.Value = 1;
            app.rsEditFieldLabel = uilabel(app.Panel);
            app.rsEditFieldLabel.Text = 'rs';
            app.rsEditField = uieditfield(app.Panel, 'numeric');
            app.rsEditField.Limits = [0 Inf];
            app.rsEditField.Value = 60;
            app.FpassEditFieldLabel_2 = uilabel(app.Panel);
            app.FpassEditFieldLabel_2.Text = 'Hz';
            app.FpassEditFieldLabel_3 = uilabel(app.Panel);
            app.FpassEditFieldLabel_3.Text = 'Hz';
            app.FpassEditFieldLabel_4 = uilabel(app.Panel);
            app.FpassEditFieldLabel_4.Text = 'Hz';
            app.FpassEditFieldLabel_5 = uilabel(app.Panel);
            app.FpassEditFieldLabel_5.Text = 'dB';
            app.FpassEditFieldLabel_6 = uilabel(app.Panel);
            app.FpassEditFieldLabel_6.Text = 'dB';
            app.UIAxes = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LPFSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end