classdef TrainSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        ButtonSelectFile    matlab.ui.control.Button
        EditPath            matlab.ui.control.EditField
        Label               matlab.ui.control.Label
        DropDownSheet       matlab.ui.control.DropDown
        Label_2             matlab.ui.control.Label
        EditFieldSampleNum  matlab.ui.control.NumericEditField
        Label_7             matlab.ui.control.Label
        EditTestNum         matlab.ui.control.NumericEditField
        Panel_2             matlab.ui.container.Panel
        Label_3             matlab.ui.control.Label
        EditInputNum        matlab.ui.control.NumericEditField
        Label_4             matlab.ui.control.Label
        EditMiddleNum       matlab.ui.control.NumericEditField
        Label_6             matlab.ui.control.Label
        EditLoopNum         matlab.ui.control.NumericEditField
        Label_8             matlab.ui.control.Label
        EditOutputNum       matlab.ui.control.NumericEditField
        ButtonOK            matlab.ui.control.Button
        ButtonCancel        matlab.ui.control.Button
        ButtonLoad          matlab.ui.control.Button
        ButtonSave          matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp  % Description
        FileSavePath ='.\特征值\青岛特征值通道修正20191102-105937.xls';
        SheetItems = {'sheet1','特征值'};
        SheetSelected = 'sheet1';
        TrainSampleNum = 70;
        TestSampleNum = 15;
        InputLayerNum = 8;
        MiddleLayerNum = 13;
        OutputLayerNum = 3;
        LoopNum = 1000;
        FileSettings = '.\Settings\DefaultSettings.mat';    % Description
    end
    
    
    methods (Access = private)
        
        function saveTrainSettings(app,mainApp)
            app.FileSavePath = app.EditPath.Value;
            app.SheetItems = app.DropDownSheet.Items;
            app.SheetSelected = app.DropDownSheet.Value;
            app.TrainSampleNum = app.EditFieldSampleNum.Value;
            app.TestSampleNum = app.EditTestNum.Value;
            app.InputLayerNum = app.EditInputNum.Value;
            app.MiddleLayerNum = app.EditMiddleNum.Value;
            app.OutputLayerNum = app.EditOutputNum.Value;
            app.LoopNum = app.EditLoopNum.Value;
            
            a.FileSavePath = app.FileSavePath;
            a.SheetItems = app.SheetItems;
            a.SheetSelected = app.SheetSelected;
            a.TrainSampleNum = app.TrainSampleNum;
            a.TestSampleNum = app.TestSampleNum;
            a.InputLayerNum = app.InputLayerNum;
            a.MiddleLayerNum = app.MiddleLayerNum;
            a.OutputLayerNum = app.OutputLayerNum;
            a.LoopNum = app.LoopNum;
            
            a.FileSettings = app.FileSettings ;
            save(app.FileSettings,'-struct','a');
            InitSettingsPath = app.FileSettings;
            save ('.\Settings\DefaultSettings.mat', 'InitSettingsPath');
            setSettingFile(mainApp,app.FileSettings);

        end
        
        function loadTrainSettings(app,mainApp)
            load(app.FileSettings); 
            app.EditPath.Value = FileSavePath;
%             app.DropDownSheet.Value = SheetSelected;
            app.EditFieldSampleNum.Value = TrainSampleNum;
            app.EditTestNum.Value = TestSampleNum;
            app.EditInputNum.Value = InputLayerNum;
            app.EditMiddleNum.Value = MiddleLayerNum;
%             app.EditMiddleNum.Value = MiddleLayerNum;
            app.EditOutputNum.Value = OutputLayerNum;
            app.EditLoopNum.Value = LoopNum;
            
            InitSettingsPath = app.FileSettings;
            save ('.\Settings\DefaultSettings.mat', 'InitSettingsPath');
            setSettingFile(mainApp,app.FileSettings);
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.CallingApp = mainApp;
            movegui(app.UIFigure,'center');
            load '.\Settings\DefaultSettings.mat';
            app.FileSettings = InitSettingsPath; % 默认文件。
            loadTrainSettings(app,mainApp);      % 
            % 初始化可以采用载入文件的方式进行。   2019.04.27
            [~,Sheets] = xlsfinfo(app.FileSavePath);
            app.DropDownSheet.Items = Sheets;
            app.SheetSelected = app.DropDownSheet.Value;
        end

        % Button pushed function: ButtonSelectFile
        function ButtonSelectFilePushed(app, event)
            [filename, pathname] = uigetfile('*.xls', '请选择特征值文件', ''); 
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.FileSavePath = [pathname,filename];
            app.EditPath.Value = app.FileSavePath;
            [~,Sheets] = xlsfinfo(app.FileSavePath);
            app.DropDownSheet.Items = Sheets;
            app.SheetSelected = app.DropDownSheet.Value;
            
%             B = xlsread(app.FileSavePath, app.SheetSelected);
%             row = size(B,1);
%             app.EditFieldSampleNum.Value = floor(row*.8);
%             app.EditTestNum.Value = row - floor(row*.8);
        end

        % Value changed function: DropDownSheet
        function DropDownSheetValueChanged(app, event)
            app.SheetSelected = app.DropDownSheet.Value;
%             msgbox(app.SheetSelected);
        end

        % Button pushed function: ButtonSave
        function ButtonSavePushed(app, event)

            [filename,pathname] = uiputfile( {'*.mat';'*.*'}, 'Save as'); 
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.FileSettings = [pathname,filename] ;
            saveTrainSettings(app,app.CallingApp);
            msgbox('设置保存成功！');
        end

        % Button pushed function: ButtonOK
        function ButtonOKPushed(app, event)
            saveTrainSettings(app,app.CallingApp);
            delete(app);
        end

        % Button pushed function: ButtonCancel
        function ButtonCancelPushed(app, event)
            delete(app);
        end

        % Button pushed function: ButtonLoad
        function ButtonLoadPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', '请选择设置文件', ''); 
            if(isequal(filename,0) || isequal(pathname,0))
                return;
            end
            app.FileSettings = [pathname,filename];
            loadTrainSettings(app,app.CallingApp);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = '样本学习参数设置';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '样本参数设置';
            app.ButtonSelectFile = uibutton(app.Panel, 'push');
            app.ButtonSelectFile.ButtonPushedFcn = createCallbackFcn(app, @ButtonSelectFilePushed, true);
            app.ButtonSelectFile.Text = '选择特征值文件';
            app.EditPath = uieditfield(app.Panel, 'text');
            app.EditPath.Editable = 'off';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '表名';
            app.DropDownSheet = uidropdown(app.Panel);
            app.DropDownSheet.Items = {'sheet 1', '特征值 初始'};
            app.DropDownSheet.ValueChangedFcn = createCallbackFcn(app, @DropDownSheetValueChanged, true);
            app.DropDownSheet.Value = 'sheet 1';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '训练样本个数';
            app.EditFieldSampleNum = uieditfield(app.Panel, 'numeric');
            app.EditFieldSampleNum.Limits = [0 1024];
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '测试样本个数';
            app.EditTestNum = uieditfield(app.Panel, 'numeric');
            app.EditTestNum.Limits = [0 1024];
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '神经网络参数设置';
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '输入层节点数';
            app.EditInputNum = uieditfield(app.Panel_2, 'numeric');
            app.EditInputNum.Limits = [3 10];
            app.EditInputNum.Value = 8;
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '隐藏层节点数';
            app.EditMiddleNum = uieditfield(app.Panel_2, 'numeric');
            app.EditMiddleNum.Limits = [10 30];
            app.EditMiddleNum.Value = 15;
            app.Label_6 = uilabel(app.Panel_2);
            app.Label_6.Text = '训练循环次数';
            app.EditLoopNum = uieditfield(app.Panel_2, 'numeric');
            app.EditLoopNum.Limits = [100 300000];
            app.EditLoopNum.Value = 1000;
            app.Label_8 = uilabel(app.Panel_2);
            app.Label_8.Text = '输出层节点数';
            app.EditOutputNum = uieditfield(app.Panel_2, 'numeric');
            app.EditOutputNum.Limits = [3 6];
            app.EditOutputNum.Value = 3;
            app.ButtonOK = uibutton(app.UIFigure, 'push');
            app.ButtonOK.ButtonPushedFcn = createCallbackFcn(app, @ButtonOKPushed, true);
            app.ButtonOK.Text = '确定';
            app.ButtonCancel = uibutton(app.UIFigure, 'push');
            app.ButtonCancel.ButtonPushedFcn = createCallbackFcn(app, @ButtonCancelPushed, true);
            app.ButtonCancel.Text = '取消';
            app.ButtonLoad = uibutton(app.UIFigure, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadPushed, true);
            app.ButtonLoad.Text = '载入设置';
            app.ButtonSave = uibutton(app.UIFigure, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePushed, true);
            app.ButtonSave.Text = '保存设置';
        end
    end

    methods (Access = public)

        % Construct app
        function app = TrainSettings(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end